classdef Jacobi_metodo2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        GraficarButton                matlab.ui.control.Button
        CleanButton                   matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        CalcularButton                matlab.ui.control.Button
        AEditFieldLabel               matlab.ui.control.Label
        AEditField                    matlab.ui.control.EditField
        IntervaloMenorEditFieldLabel  matlab.ui.control.Label
        IntervaloMenorEditField       matlab.ui.control.NumericEditField
        IntervaloMayorEditFieldLabel  matlab.ui.control.Label
        IntervaloMayorEditField       matlab.ui.control.NumericEditField
        BEditFieldLabel               matlab.ui.control.Label
        BEditField                    matlab.ui.control.EditField
        ToleranciaEditFieldLabel      matlab.ui.control.Label
        ToleranciaEditField           matlab.ui.control.NumericEditField
        JacobiLabel                   matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
          
            tumamaentanganoescierto = 300;
            Atemp=split(app.AEditField.Value,";");
   
       A = str2double(split(Atemp,","));
       
       B=str2double(split(app.BEditField.Value,","));
        
       [n,m] = size(A);
       
       %Diagonal dominante
    for i = 1:n
        [~, maxIndex] = max(abs(A(i:n, i)));
        maxIndex = maxIndex + i - 1;
        A([i, maxIndex], :) = A([maxIndex, i], :);
        B([i, maxIndex]) = B([maxIndex, i]);  
    end  
   
       x = zeros(n,1);
       xnew = zeros(n,1);
       tol = app.ToleranciaEditField.Value;
       
      
       counter = 1;
       temp=0; 
        mat = [];
        
      while (counter<tumamaentanganoescierto) % se termina cuando exede 300
        x = xnew; % actualiza x con los nuevos valores
          %mi amigo el jacobi Iteration
        for i=1:n
             S= A(i,1:i-1)*x(1:i-1)+A(i,i+1:n)*x(i+1:n);
             xnew(i) = (B(i)-S)/A(i,i); % calcula los nuevos valores de x
       
        end
    
        
        %Calcula el error y lo compara con la toleracia
        %esp=[];
        esp=zeros(n,1);
        for i=1 : n
            esp(i) = abs((xnew(i)-x(i)) / xnew(i));
        end
        %mat = [mat; counter, xnew(1), xnew(2), xnew(3), esp(1), esp(2), esp(3)];
         mat = [mat; counter, xnew', esp'];
        
        %se rompe cuando cumple con la toleracia
        if max(esp)<tol
           break;
        end
        
        counter=counter+1;% no. de iteraciones
      end
    % Crea columnas dinamicas
    arr = ["Iteration"];
    for i = 1:n
        arr = [arr, "X" + num2str(i)];
    end
    for i = 1:n
        arr = [arr, "EX" + num2str(i)];
    end
    app.UITable.ColumnName = arr;
   

    % llena tabla
    app.UITable.Data = mat(1:counter-1, :); 
    %app.UITable.Data = mat;  
   
       
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            %{ 
            % Obtén los intervalos de la interfaz de usuario
    xMin = app.IntervaloMenorEditField.Value;
    xMax = app.IntervaloMayorEditField.Value;
    
    % Obtén los datos de las iteraciones de la tabla
    mat = app.UITable.Data;
    iteraciones = mat(:, 1);
    x1_vals = mat(:, 2);
    x2_vals = mat(:, 3);
    x3_vals = mat(:, 4);

    % Graficar los valores de x1, x2 y x3
    plot(app.UIAxes, iteraciones, x1_vals, 'r', 'LineWidth', 2); hold(app.UIAxes, 'on');
    plot(app.UIAxes, iteraciones, x2_vals, 'g', 'LineWidth', 2);
    plot(app.UIAxes, iteraciones, x3_vals, 'b', 'LineWidth', 2);

    % Ajustar los límites del eje x
       xlim(app.UIAxes, [xMin, xMax]);
       ylim(app.UIAxes, [-10, 10]); % Ajusta los límites del eje y

    % Agregar títulos y etiquetas
     title(app.UIAxes, 'Convergencia del método de Jacobi');
    xlabel(app.UIAxes, 'Iteración');
    ylabel(app.UIAxes, 'Valor de x');
    legend(app.UIAxes, 'x1', 'x2', 'x3');
    %}
            xlim(app.UIAxes, [-10, 10]);
            ylim(app.UIAxes, [-10, 10]); % Ajusta los límites del eje y
    % Cargar la imagen
    img = imread('Señorita1.PNG');

    % Mostrar la imagen centrada en la gráfica
    image(app.UIAxes, 'CData', img, 'XData', [-10, 10], 'YData', [-10 10]);

    % Finalizar la actualización de la gráfica
    hold(app.UIAxes, 'off');


        end

        % Button pushed function: CleanButton
        function CleanButtonPushed(app, event)
            cla(app.UIAxes);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Grafica')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.CleanButton = uibutton(app.UIFigure, 'push');
            app.CleanButton.ButtonPushedFcn = createCallbackFcn(app, @CleanButtonPushed, true);
            app.CleanButton.Text = 'Clean';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Iteración'; 'x'; 'y'; 'z'; 'EaX'; 'Eay'; 'Eaz'};
            app.UITable.RowName = {};
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.AEditFieldLabel = uilabel(app.UIFigure);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.UIFigure, 'text');
            app.IntervaloMenorEditFieldLabel = uilabel(app.UIFigure);
            app.IntervaloMenorEditFieldLabel.Text = 'Intervalo Menor';
            app.IntervaloMenorEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntervaloMayorEditFieldLabel = uilabel(app.UIFigure);
            app.IntervaloMayorEditFieldLabel.Text = 'Intervalo Mayor';
            app.IntervaloMayorEditField = uieditfield(app.UIFigure, 'numeric');
            app.BEditFieldLabel = uilabel(app.UIFigure);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.UIFigure, 'text');
            app.ToleranciaEditFieldLabel = uilabel(app.UIFigure);
            app.ToleranciaEditFieldLabel.Text = 'Tolerancia';
            app.ToleranciaEditField = uieditfield(app.UIFigure, 'numeric');
            app.JacobiLabel = uilabel(app.UIFigure);
            app.JacobiLabel.Text = 'Jacobi';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Jacobi_metodo2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end