classdef Newton_metodo3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        UITable                      matlab.ui.control.Table
        CalcularButton               matlab.ui.control.Button
        NewtonRaphsonLabel           matlab.ui.control.Label
        Ecuacion1EditFieldLabel      matlab.ui.control.Label
        Ecuacion1EditField           matlab.ui.control.EditField
        Ecuacion2EditFieldLabel      matlab.ui.control.Label
        Ecuacion2EditField           matlab.ui.control.EditField
        ErrorAdmitidoEditFieldLabel  matlab.ui.control.Label
        ErrorAdmitidoEditField       matlab.ui.control.NumericEditField
        x0EditFieldLabel             matlab.ui.control.Label
        x0EditField                  matlab.ui.control.NumericEditField
        y0EditFieldLabel             matlab.ui.control.Label
        y0EditField                  matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            arr = zeros([1 5]);
            app.UITable.Data = arr;
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
           % Button pushed function: CalcularButton

    eq1 = app.Ecuacion1EditField.Value;
    eq2 = app.Ecuacion2EditField.Value;

    syms x y

    es = app.ErrorAdmitidoEditField.Value;
    f1 = str2sym(eq1);
    f2 = str2sym(eq2);
    df1_x = diff(f1, x);
    df1_y = diff(f1, y);
    df2_x = diff(f2, x);
    ea = zeros([1 2]);
    df2_y = diff(f2, y);
    num1 = app.x0EditField.Value;
    num2 = app.y0EditField.Value;
    arr = [num1, num2];

    % Inicializa el contador
    cont = 1;

    f1v = subs(f1, [x, y], arr);
    f2v = subs(f2, [x, y], arr);
    df1_xv = subs(df1_x, [x, y], arr);
    df1_yv = subs(df1_y, [x, y], arr);
    df2_xv = subs(df2_x, [x, y], arr);
    df2_yv = subs(df2_y, [x, y], arr);

    jaco = (df1_xv * df2_yv)-(df2_xv * df1_yv)

    sigX = arr(1) - (( f1v*df2_yv - f2v*df1_yv)/jaco)
    sigY = arr(2) - (( f2v*df1_xv - f1v*df2_xv)/jaco)
    errX = abs(((sigX - arr(1,1))/sigX)*100);
    errY = abs(((sigY - arr(1,2))/sigY)*100);
    arr = [arr;double(sigX),double(sigY)];
    ea = [ea; double(errX), double(errY)];

    % Agrega el contador a los datos de la tabla
    tableData = [cont, arr(end,:), ea(end,:)];
    

    i = 2

    while any(ea(i,:) > es)

        f1v = subs(f1, [x, y], [arr(i,1),arr(i,2)]);
        f2v = subs(f2, [x, y], [arr(i,1),arr(i,2)]);
        df1_xv = subs(df1_x, [x, y], [arr(i,1),arr(i,2)]);
        df1_yv = subs(df1_y, [x, y], [arr(i,1),arr(i,2)]);
        df2_xv = subs(df2_x, [x, y], [arr(i,1),arr(i,2)]);
        df2_yv = subs(df2_y, [x, y], [arr(i,1),arr(i,2)]);

        jaco = (df1_xv * df2_yv)-(df2_xv * df1_yv)

        sigX = arr(i,1) - (( f1v*df2_yv - f2v*df1_yv)/jaco)
        sigY = arr(i,2) - (( f2v*df1_xv - f1v*df2_xv)/jaco)
        errX = abs(((sigX - arr(i,1))/sigX)*100);
        errY = abs(((sigY - arr(i,2))/sigY)*100);
        arr = [arr;double(sigX),double(sigY)];
        ea = [ea; double(errX), double(errY)];

        % Incrementa el contador
        cont = cont + 1;

        % Agrega una nueva fila a los datos de la tabla
        tableData = [tableData; cont, arr(end,:), ea(end,:)];

        i = i+1;

    end
    y = arr(i,2);
    hold(app.UIAxes,'on');
    xline(app.UIAxes,arr(i,1),'r');
    fplot(app.UIAxes,y,'r');
    fimplicit(app.UIAxes,f1);
    fimplicit(app.UIAxes,f2);

    hold(app.UIAxes,'off');
    app.UITable.Data = tableData;


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Grafica')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Iteración'; 'x'; 'y'; 'EaX'; 'Eay'};
            app.UITable.RowName = {};
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.NewtonRaphsonLabel = uilabel(app.UIFigure);
            app.NewtonRaphsonLabel.Text = 'Newton-Raphson';
            app.Ecuacion1EditFieldLabel = uilabel(app.UIFigure);
            app.Ecuacion1EditFieldLabel.Text = 'Ecuacion 1';
            app.Ecuacion1EditField = uieditfield(app.UIFigure, 'text');
            app.Ecuacion2EditFieldLabel = uilabel(app.UIFigure);
            app.Ecuacion2EditFieldLabel.Text = 'Ecuacion 2';
            app.Ecuacion2EditField = uieditfield(app.UIFigure, 'text');
            app.ErrorAdmitidoEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorAdmitidoEditFieldLabel.Text = 'Error Admitido';
            app.ErrorAdmitidoEditField = uieditfield(app.UIFigure, 'numeric');
            app.x0EditFieldLabel = uilabel(app.UIFigure);
            app.x0EditFieldLabel.Text = 'x0';
            app.x0EditField = uieditfield(app.UIFigure, 'numeric');
            app.y0EditFieldLabel = uilabel(app.UIFigure);
            app.y0EditFieldLabel.Text = 'y0';
            app.y0EditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Newton_metodo3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end