classdef Sementos_impares < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SegmentosimparesLabel        matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        CalcularButton               matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        fxEditFieldLabel             matlab.ui.control.Label
        fxEditField                  matlab.ui.control.EditField
        aEditFieldLabel              matlab.ui.control.Label
        aEditField                   matlab.ui.control.NumericEditField
        bEditFieldLabel              matlab.ui.control.Label
        bEditField                   matlab.ui.control.NumericEditField
        nEditFieldLabel              matlab.ui.control.Label
        nEditField                   matlab.ui.control.NumericEditField
        erroradmitidoEditFieldLabel  matlab.ui.control.Label
        erroradmitidoEditField       matlab.ui.control.NumericEditField
        CalcularporerrorButton       matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            funcion = app.fxEditField.Value;
            f = str2func(['@(x)' funcion]);
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            n = app.nEditField.Value;

                  h = ((b-a)/n);
                sum1 = (f(a)+f(h*(n-3)));
                sum2 = (f(h*(n-3))+f(b));
                    disp(sum1);
                    disp(sum2);
                    disp(h);
                for i = 1 : n - 4
                    x=i*h;
                    disp (i);
                   if mod(i,2) == 0
                       sum1 = sum1 + 2 * f(x);
                   else
                       sum1 = sum1 + 4 * f(x);
                   end
                    
                end
               I1 = (h / 3) * sum1;
                for i = n - 3 : n-1
                    x = i*h;
                     disp (i);
                    if mod(i,3)==0
                        sum2 = sum2 + f(x);
                    else
                        sum2 = sum2 + 3*f(x);
                    end
                end
                 I2 = (3 * h / 8) * sum2;
                
                I = I1+I2; 
                real = integral(f, a, b); 
                Er = ((real - I) / real) * 100; 
                datos = [n, h, I, Er]; 
                app.UITable.Data = datos; 
                
                hold(app.UIAxes, 'on');
                    cla(app.UIAxes);
                    xline(app.UIAxes, 0, 'r'); % Línea vertical en x=0
                    xline(app.UIAxes, a, 'g'); % Línea vertical en x=a
                    xline(app.UIAxes, b, 'g'); % Línea vertical en x=b
                    fplot(app.UIAxes, f, [a b], 'b'); % Graficar la función f en el intervalo [a, b]
                    % Calcular puntos para el área sombreada
x_fill = linspace(a, b, 1000); % Generar puntos entre a y b
y_fill = arrayfun(f, x_fill); % Evaluar la función en esos puntos
fill(app.UIAxes, [a, x_fill, b], [0, y_fill, 0], 'b', 'FaceAlpha', 0.1, 'EdgeColor', 'none'); % Crear área sombreada
for i = 1:n
    x_vert = a + (i-1) * h;
    y_vert = f(x_vert);
    line(app.UIAxes, [x_vert, x_vert], [0, y_vert], 'Color', 'magenta', 'LineWidth', 1.5);
end
text(app.UIAxes, a, f(a), sprintf('a = %f', a), 'VerticalAlignment', 'bottom');
text(app.UIAxes, b, f(b), sprintf('b = %f', b), 'VerticalAlignment', 'bottom');
text(app.UIAxes, (a+b)/2, max(y_fill)/2, sprintf('Integral ≈ %f', I), 'HorizontalAlignment', 'center');


hold(app.UIAxes, 'off');


        end

        % Button pushed function: CalcularporerrorButton
        function CalcularporerrorButtonPushed(app, event)
            eq = app.fxEditField.Value;
            
            f = str2func(['@(x)' eq]);
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            n = 3;
            err = 100;
            erra = app.erroradmitidoEditField.Value;
            real = integral(f, a, b);
            while abs(err) > erra
                a = app.aEditField.Value;
                b = app.bEditField.Value;
                n = n + 2;
                h = ((b-a)/n);
                b = b - (h*3);
                n = n - 3;
            
                res = f(a);
                i = a ;
                con = 0;
                aux = 0;
                aux2 = 0;
                while  con < ((n-2)/2)
                    i = i + h;
                    aux = aux + f(i);
                    i = i + h;
                    aux2 = aux2 + f(i);
                    con = con + 1;
                end 
                i = i + h;
                aux = aux + f(i);
                aux = (aux * 4);
                aux2 = (aux2 * 2);
                res = (res + aux + aux2 + f(b));
                res = (res/(3*n));
                res = (res * (b-a));            
                hold(app.UIAxes, 'on');
                    cla(app.UIAxes);
                    xline(app.UIAxes, 0, 'r'); % Línea vertical en x=0
                    xline(app.UIAxes, a, 'g'); % Línea vertical en x=a
                    xline(app.UIAxes, b, 'g'); % Línea vertical en x=b
                    fplot(app.UIAxes, f, [a b], 'b'); % Graficar la función f en el intervalo [a, b]
                    % Calcular puntos para el área sombreada
x_fill = linspace(a, b, 1000); % Generar puntos entre a y b
y_fill = arrayfun(f, x_fill); % Evaluar la función en esos puntos
fill(app.UIAxes, [a, x_fill, b], [0, y_fill, 0], 'b', 'FaceAlpha', 0.1, 'EdgeColor', 'none'); % Crear área sombreada
for i = 1:n
    x_vert = a + (i-1) * h;
    y_vert = f(x_vert);
    line(app.UIAxes, [x_vert, x_vert], [0, y_vert], 'Color', 'magenta', 'LineWidth', 1.5);
end
text(app.UIAxes, a, f(a), sprintf('a = %f', a), 'VerticalAlignment', 'bottom');
text(app.UIAxes, b, f(b), sprintf('b = %f', b), 'VerticalAlignment', 'bottom');
text(app.UIAxes, (a+b)/2, max(y_fill)/2, sprintf('Integral ≈ %f', res), 'HorizontalAlignment', 'center');


hold(app.UIAxes, 'off');
            
            
            %%%%%%%%%%%%%%%
            
                a = b;
                b = app.bEditField.Value;
                h = ((b-a)/3);
                res2 = (f(a) + (3*f(a+h)) + (3*f(a+h+h)) + f(b));
                res2 = (res2/8);
                res2 = (res2 * (b-a));
            
                res = res + res2;
                err = (((real-res)/real)*100);
                n = n + 3;
            end
            
            arr = [n h res err];
            app.UITable.Data = arr;
            
           hold(app.UIAxes, 'on');
                   
                    xline(app.UIAxes, 0, 'r'); % Línea vertical en x=0
                    xline(app.UIAxes, a, 'g'); % Línea vertical en x=a
                    xline(app.UIAxes, b, 'g'); % Línea vertical en x=b
                    fplot(app.UIAxes, f, [a b], 'b'); % Graficar la función f en el intervalo [a, b]
                    % Calcular puntos para el área sombreada
x_fill = linspace(a, b, 1000); % Generar puntos entre a y b
y_fill = arrayfun(f, x_fill); % Evaluar la función en esos puntos
fill(app.UIAxes, [a, x_fill, b], [0, y_fill, 0], 'b', 'FaceAlpha', 0.1, 'EdgeColor', 'none'); % Crear área sombreada
for i = 1:n
    x_vert = a + (i-1) * h;
    y_vert = f(x_vert);
    line(app.UIAxes, [x_vert, x_vert], [0, y_vert], 'Color', 'magenta', 'LineWidth', 1.5);
end
text(app.UIAxes, a, f(a), sprintf('a = %f', a), 'VerticalAlignment', 'bottom');
text(app.UIAxes, b, f(b), sprintf('b = %f', b), 'VerticalAlignment', 'bottom');
text(app.UIAxes, (a+b)/2, max(y_fill)/2, sprintf('Integral ≈ %f', res), 'HorizontalAlignment', 'center');


hold(app.UIAxes, 'off');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SegmentosimparesLabel = uilabel(app.UIFigure);
            app.SegmentosimparesLabel.Text = 'Segmentos impares';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Grafica')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'i'; 'h'; 'Aproximacion'; 'Er'};
            app.UITable.RowName = {};
            app.fxEditFieldLabel = uilabel(app.UIFigure);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.fxEditField = uieditfield(app.UIFigure, 'text');
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.UIFigure, 'numeric');
            app.erroradmitidoEditFieldLabel = uilabel(app.UIFigure);
            app.erroradmitidoEditFieldLabel.Text = 'error admitido';
            app.erroradmitidoEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalcularporerrorButton = uibutton(app.UIFigure, 'push');
            app.CalcularporerrorButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularporerrorButtonPushed, true);
            app.CalcularporerrorButton.Text = 'Calcular por error';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Sementos_impares

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end