classdef Trapecio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        fxEditFieldLabel             matlab.ui.control.Label
        fxEditField                  matlab.ui.control.EditField
        aEditField_2Label            matlab.ui.control.Label
        aEditField                   matlab.ui.control.NumericEditField
        bEditFieldLabel              matlab.ui.control.Label
        bEditField                   matlab.ui.control.NumericEditField
        nEditFieldLabel              matlab.ui.control.Label
        nEditField                   matlab.ui.control.NumericEditField
        CalcularButton               matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        CalcularporerrorButton       matlab.ui.control.Button
        erroradmitidoEditFieldLabel  matlab.ui.control.Label
        erroradmitidoEditField       matlab.ui.control.NumericEditField
        TrapecioLabel                matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            eq = app.fxEditField.Value;
           
            
            f = str2func(['@(x)' eq]);
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            n = app.nEditField.Value;
            h = ((b-a)/n);
            res = f(a);
            i = a ;
            con = 0;
            aux = 0;
            while  con < n-1
                i = i + h;
                aux = aux + f(i);
                con = con + 1;
            end 
            aux = (aux * 2);
            res = (res + aux + f(b));
            res = (res * (h/2));
            real = integral(f, a, b);
            err = (((real-res)/real)*100);
            
            arr = [n h res err];
            app.UITable.Data = arr;
            
            hold(app.UIAxes, 'on');
                    cla(app.UIAxes);
                    xline(app.UIAxes, 0, 'r'); % Línea vertical en x=0
                    xline(app.UIAxes, a, 'g'); % Línea vertical en x=a
                    xline(app.UIAxes, b, 'g'); % Línea vertical en x=b
                    fplot(app.UIAxes, f, [a b], 'b'); % Graficar la función f en el intervalo [a, b]
                    % Calcular puntos para el área sombreada
x_fill = linspace(a, b, 1000); % Generar puntos entre a y b
y_fill = arrayfun(f, x_fill); % Evaluar la función en esos puntos
fill(app.UIAxes, [a, x_fill, b], [0, y_fill, 0], 'b', 'FaceAlpha', 0.1, 'EdgeColor', 'none'); % Crear área sombreada
for i = 1:n
    x_vert = a + (i-1) * h;
    y_vert = f(x_vert);
    line(app.UIAxes, [x_vert, x_vert], [0, y_vert], 'Color', 'magenta', 'LineWidth', 1.5);
end
text(app.UIAxes, a, f(a), sprintf('a = %f', a), 'VerticalAlignment', 'bottom');
text(app.UIAxes, b, f(b), sprintf('b = %f', b), 'VerticalAlignment', 'bottom');
text(app.UIAxes, (a+b)/2, max(y_fill)/2, sprintf('Integral ≈ %f', res), 'HorizontalAlignment', 'center');


hold(app.UIAxes, 'off');
        end

        % Button pushed function: CalcularporerrorButton
        function CalcularporerrorButtonPushed(app, event)
            eq = app.fxEditField.Value;
            
            f = str2func(['@(x)' eq]);
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            err = 100;
            res = 0;
            real = 0;
            n = 1;
            erra = app.erroradmitidoEditField.Value;
            while err > erra 
                n = n + 1;
                h = ((b-a)/n);
                res = f(a);
                i = a ;
                con = 0;
                aux = 0;
                while  con < n-1
                    i = i + h;
                    aux = aux + f(i);
                    con = con + 1;
                end 
                aux = (aux * 2);
                res = (res + aux + f(b));
                res = (res * (h/2));
                real = integral(f, a, b);
                err = (((real-res)/real)*100);
            end
            
            arr = [n h res err];
            app.UITable.Data = arr;
            hold(app.UIAxes, 'on');
                    cla(app.UIAxes);
                    xline(app.UIAxes, 0, 'r'); % Línea vertical en x=0
                    xline(app.UIAxes, a, 'g'); % Línea vertical en x=a
                    xline(app.UIAxes, b, 'g'); % Línea vertical en x=b
                    fplot(app.UIAxes, f, [a b], 'b'); % Graficar la función f en el intervalo [a, b]
                    % Calcular puntos para el área sombreada
x_fill = linspace(a, b, 1000); % Generar puntos entre a y b
y_fill = arrayfun(f, x_fill); % Evaluar la función en esos puntos
fill(app.UIAxes, [a, x_fill, b], [0, y_fill, 0], 'b', 'FaceAlpha', 0.1, 'EdgeColor', 'none'); % Crear área sombreada
for i = 1:n
    x_vert = a + (i-1) * h;
    y_vert = f(x_vert);
    line(app.UIAxes, [x_vert, x_vert], [0, y_vert], 'Color', 'magenta', 'LineWidth', 1.5);
end
text(app.UIAxes, a, f(a), sprintf('a = %f', a), 'VerticalAlignment', 'bottom');
text(app.UIAxes, b, f(b), sprintf('b = %f', b), 'VerticalAlignment', 'bottom');
text(app.UIAxes, (a+b)/2, max(y_fill)/2, sprintf('Integral ≈ %f', res), 'HorizontalAlignment', 'center');


hold(app.UIAxes, 'off');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.fxEditFieldLabel = uilabel(app.UIFigure);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.fxEditField = uieditfield(app.UIFigure, 'text');
            app.aEditField_2Label = uilabel(app.UIFigure);
            app.aEditField_2Label.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'i'; 'h'; 'Aproximacion'; 'Er'};
            app.UITable.RowName = {};
            app.CalcularporerrorButton = uibutton(app.UIFigure, 'push');
            app.CalcularporerrorButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularporerrorButtonPushed, true);
            app.CalcularporerrorButton.Text = 'Calcular por error';
            app.erroradmitidoEditFieldLabel = uilabel(app.UIFigure);
            app.erroradmitidoEditFieldLabel.Text = 'error admitido';
            app.erroradmitidoEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrapecioLabel = uilabel(app.UIFigure);
            app.TrapecioLabel.Text = 'Trapecio';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Grafica')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = Trapecio

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end