classdef metodo1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BiseccionLabel                matlab.ui.control.Label
        CalcularButton                matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        GraficarButton                matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        IngreseFuncinEditFieldLabel   matlab.ui.control.Label
        IngreseFuncinEditField        matlab.ui.control.EditField
        ErroradmitidoLabel            matlab.ui.control.Label
        ErroradmitidoEditField        matlab.ui.control.NumericEditField
        IntervaloMenorEditFieldLabel  matlab.ui.control.Label
        IntervaloMenorEditField       matlab.ui.control.NumericEditField
        IntervaloMayorEditFieldLabel  matlab.ui.control.Label
        IntervaloMayorEditField       matlab.ui.control.NumericEditField
        CleanButton                   matlab.ui.control.Button
        bEditFieldLabel               matlab.ui.control.Label
        bEditField                    matlab.ui.control.NumericEditField
        aEditFieldLabel               matlab.ui.control.Label
        aEditField                    matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            pchar = app.IngreseFuncinEditField.Value;
            f = str2func(['@(x)' pchar]);
            flinea = 0;
            intervalo_menor = app.IntervaloMenorEditField.Value;
            intervalo_mayor = app.IntervaloMayorEditField.Value;
            hold(app.UIAxes,'on');
            cla(app.UIAxes);
            xline(app.UIAxes,0);
            fplot(app.UIAxes, f, [intervalo_menor, intervalo_mayor]);
            fplot(app.UIAxes,flinea,[intervalo_menor, intervalo_mayor]);
             ylim(app.UIAxes, [-10, 10]); % Ajusta los límites del eje y
            hold(app.UIAxes,'off');
        
        end

        % Button pushed function: CleanButton
        function CleanButtonPushed(app, event)
            cla(app.UIAxes);
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            ea = app.ErroradmitidoEditField.Value;
            e = 100;
            a = double(app.aEditField.Value);
            b = double(app.bEditField.Value);
            pchar = app.IngreseFuncinEditField.Value;
            f = inline(pchar);
            flinea = 0;
            i = 1;
            xi = (a+b)/2;
            mat = [i,a,b,f(xi),f(xi)*f(a),0];
            
            if (f(a)*f(xi)<0)
                b = xi;
            end
            if (f(xi)*f(b)<0)
                a = xi;
            end
            
            hold(app.UIAxes,'on');
            xline(app.UIAxes,xi);
            hold(app.UIAxes,'off');
            
            while e >= ea
                xi_ant = xi;
                xi = (a+b)/2;
                e = abs(((xi-xi_ant)/xi)*100);
                i = i+1;
                %mat = [mat;i,a,b,xi,f(xi),f(xi)*f(a),e];
                 mat(i,1) = i;
                        mat(i,2) = a;
                        mat(i,3) = b;
                        mat(i,4) = xi;
                        mat(i,5) = f(xi);
                        mat(i,6) = f(xi)*f(a);
                        mat(i,7) = e;
                        
                if (f(a)*f(xi)<0)
                    b = xi;
                end
                if (f(xi)*f(b)<0)
                    a = xi;
                end
                
                hold(app.UIAxes,'on');
                xline(app.UIAxes,xi);
                hold(app.UIAxes,'off');
                app.UITable.Data = mat;
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.BiseccionLabel = uilabel(app.UIFigure);
            app.BiseccionLabel.Text = 'Biseccion';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'i'; 'a'; 'b'; 'Xi'; 'f(Xi)'; 'f(Xif(a)'; 'Ea'};
            app.UITable.RowName = {};
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Grafica')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.IngreseFuncinEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseFuncinEditFieldLabel.Text = 'Ingrese Función';
            app.IngreseFuncinEditField = uieditfield(app.UIFigure, 'text');
            app.ErroradmitidoLabel = uilabel(app.UIFigure);
            app.ErroradmitidoLabel.Text = 'Error admitido (%)';
            app.ErroradmitidoEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntervaloMenorEditFieldLabel = uilabel(app.UIFigure);
            app.IntervaloMenorEditFieldLabel.Text = 'Intervalo Menor';
            app.IntervaloMenorEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntervaloMayorEditFieldLabel = uilabel(app.UIFigure);
            app.IntervaloMayorEditFieldLabel.Text = 'Intervalo Mayor';
            app.IntervaloMayorEditField = uieditfield(app.UIFigure, 'numeric');
            app.CleanButton = uibutton(app.UIFigure, 'push');
            app.CleanButton.ButtonPushedFcn = createCallbackFcn(app, @CleanButtonPushed, true);
            app.CleanButton.Text = 'Clean';
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = metodo1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end