classdef metodo2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ReglafalsamejoradaLabel       matlab.ui.control.Label
        aEditFieldLabel               matlab.ui.control.Label
        aEditField                    matlab.ui.control.NumericEditField
        bEditFieldLabel               matlab.ui.control.Label
        bEditField                    matlab.ui.control.NumericEditField
        UITable                       matlab.ui.control.Table
        IngresaFuncinEditFieldLabel   matlab.ui.control.Label
        IngresaFuncinEditField        matlab.ui.control.EditField
        IntervalomenorEditFieldLabel  matlab.ui.control.Label
        IntervalomenorEditField       matlab.ui.control.NumericEditField
        IntervalomayorEditFieldLabel  matlab.ui.control.Label
        IntervalomayorEditField       matlab.ui.control.NumericEditField
        GraficarButton                matlab.ui.control.Button
        CleanButton                   matlab.ui.control.Button
        ErroradmitidoEditFieldLabel   matlab.ui.control.Label
        ErroradmitidoEditField        matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
        CalcularButton                matlab.ui.control.Button
    end

    methods (Access = private)

        % Callback function
        function menuButtonPushed(app, event)
            metodo
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            funci = app.IngresaFuncinEditField.Value;
            f = str2func(['@(x)' funci]);
            flinea = 0;
            intervalo_menor = app.IntervalomenorEditField.Value;
            intervalo_mayor = app.IntervalomayorEditField.Value;
            
            hold(app.UIAxes,'on');
            cla(app.UIAxes);
            xline(app.UIAxes,0);
            fplot(app.UIAxes, f, [intervalo_menor, intervalo_mayor]);
            fplot(app.UIAxes,flinea,[intervalo_menor, intervalo_mayor]);
            ylim(app.UIAxes,[-10, 10]);
            hold(app.UIAxes,'off');
        end

        % Button pushed function: CleanButton
        function CleanButtonPushed(app, event)
            cla(app.UIAxes);
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            pchar = app.IngresaFuncinEditField.Value;
            f = inline(pchar);
            ea = app.ErroradmitidoEditField.Value;
            a = double(app.aEditField.Value);
            b = double(app.bEditField.Value);
            
            
            fa = f(a);
            fb = f(b);
            i = 1;
            ia = 0;
            ib = 0;
            bucle = 1;
            xiAnt = b;
            xi = b - ((fb*(a-b))/(fa-fb));
            e = abs(((xi-xiAnt)/xi)*100); 
            hold (app.UIAxes,'on');
            xline(app.UIAxes,xi);
            hold (app.UIAxes,'off');
            fi = f(xi);
            prueba = fa*fi;
            mat = [i,a,b,fa,fb,xi,f(xi),prueba,e];
            
            while bucle == 1
                i = i+1;
                
                if prueba < 0
                    b = xi;
                    fb = f(b);
                    ib = 0;
                    ia = ia+1;
                    if ia>=2
                        fa=fa/2;
                    end
                else
                    if prueba > 0
                        a = xi;
                        fa = f(a);
                        ia = 0;
                        ib = ib+1;
                        if ib>=2
                           fb = fb/2; 
                        end
                    
                    end
                        
                end
                
                xiAnt = xi;
                xi = b - ((fb*(a-b))/(fa-fb));
                if xi ~= 0
                    e = abs(((xi-xiAnt)/xi)*100); 
                end
                fi = f(xi);
                prueba = fa*fi;
                mat = [mat;i,a,b,fa,fb,xi,f(xi),prueba,e];
                app.UITable.Data = mat;
                hold (app.UIAxes,'on');
                xline(app.UIAxes,xi);
                hold (app.UIAxes,'off');
                if e<ea
                        break;
                end
                
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ReglafalsamejoradaLabel = uilabel(app.UIFigure);
            app.ReglafalsamejoradaLabel.Text = 'Regla falsa mejorada';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'i'; 'ia'; 'bi'; 'f(ai)'; 'f(bi)'; 'xi+1'; 'f(xi+1)'; 'f(ai)f(x+a)'; 'Ea'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Grafica')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.IngresaFuncinEditFieldLabel = uilabel(app.UIFigure);
            app.IngresaFuncinEditFieldLabel.Text = 'Ingresa Función';
            app.IngresaFuncinEditField = uieditfield(app.UIFigure, 'text');
            app.IntervalomenorEditFieldLabel = uilabel(app.UIFigure);
            app.IntervalomenorEditFieldLabel.Text = 'Intervalo menor';
            app.IntervalomenorEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntervalomayorEditFieldLabel = uilabel(app.UIFigure);
            app.IntervalomayorEditFieldLabel.Text = 'Intervalo mayor';
            app.IntervalomayorEditField = uieditfield(app.UIFigure, 'numeric');
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.CleanButton = uibutton(app.UIFigure, 'push');
            app.CleanButton.ButtonPushedFcn = createCallbackFcn(app, @CleanButtonPushed, true);
            app.CleanButton.Text = 'Clean';
            app.ErroradmitidoEditFieldLabel = uilabel(app.UIFigure);
            app.ErroradmitidoEditFieldLabel.Text = 'Error admitido';
            app.ErroradmitidoEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = metodo2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end