classdef metodo3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NewtonRaphsonLabel            matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        UIAxes                        matlab.ui.control.UIAxes
        CalcularButton                matlab.ui.control.Button
        IngresefuncinEditFieldLabel   matlab.ui.control.Label
        IngresefuncinEditField        matlab.ui.control.EditField
        IntervalomenorEditFieldLabel  matlab.ui.control.Label
        IntervalomenorEditField       matlab.ui.control.NumericEditField
        IntervalomayorEditFieldLabel  matlab.ui.control.Label
        IntervalomayorEditField       matlab.ui.control.NumericEditField
        GraficarButton                matlab.ui.control.Button
        CleanButton                   matlab.ui.control.Button
        ValorinicialEditFieldLabel    matlab.ui.control.Label
        ValorinicialEditField         matlab.ui.control.NumericEditField
        ErroradmitidoEditFieldLabel   matlab.ui.control.Label
        ErroradmitidoEditField        matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Callback function
        function menuButtonPushed(app, event)
            metodo
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
           
            funcioncita = app.IngresefuncinEditField.Value;
            f = str2func(['@(x)' funcioncita]);
            flinea = 0;
            intervalo_menor = app.IntervalomenorEditField.Value;
            intervalo_mayor = app.IntervalomayorEditField.Value;
            
            hold(app.UIAxes,'on');
            cla(app.UIAxes);
            xline(app.UIAxes,0);
            fplot(app.UIAxes, f, [intervalo_menor, intervalo_mayor]);
            fplot(app.UIAxes,flinea,[intervalo_menor, intervalo_mayor]);
            ylim(app.UIAxes, [-10, 10]); % Ajusta los límites del eje y
            hold(app.UIAxes,'off');


        end

        % Button pushed function: CleanButton
        function CleanButtonPushed(app, event)
            cla(app.UIAxes);
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
         %{
            ea = app.ErroradmitidoEditField.Value;
            e = 100;
            vi = app.ValorinicialEditField.Value;
            syms x;
            funci = app.IngresefuncinEditField.Value;
            f = str2func(['@(x)' funci]);
            df = diff(f(x), x);
            df_func = matlabFunction(df);
            flinea = 0;
            i = 1;
            fnormal = f(vi);
            fderivada = df_func(vi);
            xi = vi - ( fnormal / fderivada);
            
            mat = [i,xi, fnormal,fderivada,0];
            
            vi=xi;
            
            hold (app.UIAxes,'on');
            xline(app.UIAxes,xi);
            hold (app.UIAxes,'off');
            
             while e >= ea
                fnormal = f(vi);
                fderivada = df_func(vi);
                
                next_vi = vi - fnormal / fderivada;
                
                e = abs((next_vi - vi) / next_vi) * 100;
                
                i = i+1;
                xi = next_vi; % Actualiza xi
                mat = [mat; i, xi, fnormal, fderivada, e];
                
                % Actualiza la tabla en la GUI
                app.UITable.Data = mat;
                
                vi = next_vi;
             end
                
            %}
            
         ea = app.ErroradmitidoEditField.Value;
        e = 100;
        h = 0.001;
        vi = app.ValorinicialEditField.Value;
        funci = app.IngresefuncinEditField.Value;
        f = str2func(['@(x)' funci]);
        
        % Define una función para calcular la derivada
        derivada = @(f, x, h) (f(x + h) - f(x)) / h;
        
        i = 0;
        
        % Inicializa 'mat' como una matriz vacía
        mat = [];
        
        while e >= ea || i == 0
            fnormal = f(vi);
            fderivada = derivada(f, vi, h);
            
            xi = vi - (fnormal / fderivada);
            
            if i > 0
                e = abs((xi - vi) / xi) * 100;
            end
            
            i = i + 1;
            mat = [mat; i, xi, fnormal, fderivada, e];
            
            % Actualiza la tabla en la GUI
            hold(app.UIAxes,'on');
            xline(app.UIAxes,xi);
            hold(app.UIAxes,'off');
        
            app.UITable.Data = mat;
            
            vi = xi;
        end
                    
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NewtonRaphsonLabel = uilabel(app.UIFigure);
            app.NewtonRaphsonLabel.Text = 'Newton Raphson';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'i'; 'Xi'; 'f(Xi)'; 'f''(xi)'; 'Ea'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Grafica')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.IngresefuncinEditFieldLabel = uilabel(app.UIFigure);
            app.IngresefuncinEditFieldLabel.Text = 'Ingrese función';
            app.IngresefuncinEditField = uieditfield(app.UIFigure, 'text');
            app.IntervalomenorEditFieldLabel = uilabel(app.UIFigure);
            app.IntervalomenorEditFieldLabel.Text = 'Intervalo menor';
            app.IntervalomenorEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntervalomayorEditFieldLabel = uilabel(app.UIFigure);
            app.IntervalomayorEditFieldLabel.Text = 'Intervalo mayor';
            app.IntervalomayorEditField = uieditfield(app.UIFigure, 'numeric');
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.CleanButton = uibutton(app.UIFigure, 'push');
            app.CleanButton.ButtonPushedFcn = createCallbackFcn(app, @CleanButtonPushed, true);
            app.CleanButton.Text = 'Clean';
            app.ValorinicialEditFieldLabel = uilabel(app.UIFigure);
            app.ValorinicialEditFieldLabel.Text = 'Valor inicial';
            app.ValorinicialEditField = uieditfield(app.UIFigure, 'numeric');
            app.ErroradmitidoEditFieldLabel = uilabel(app.UIFigure);
            app.ErroradmitidoEditFieldLabel.Text = 'Error admitido';
            app.ErroradmitidoEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = metodo3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end