classdef metodo4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SecanteLabel                  matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        UIAxes                        matlab.ui.control.UIAxes
        XoEditFieldLabel              matlab.ui.control.Label
        XoEditField                   matlab.ui.control.NumericEditField
        CalcularButton                matlab.ui.control.Button
        XiEditFieldLabel              matlab.ui.control.Label
        XiEditField                   matlab.ui.control.NumericEditField
        IngreseFuncinEditFieldLabel   matlab.ui.control.Label
        IngreseFuncinEditField        matlab.ui.control.EditField
        IntervalomenorEditFieldLabel  matlab.ui.control.Label
        IntervalomenorEditField       matlab.ui.control.NumericEditField
        IntervalomayorEditFieldLabel  matlab.ui.control.Label
        IntervalomayorEditField       matlab.ui.control.NumericEditField
        ErroradmitidoEditFieldLabel   matlab.ui.control.Label
        ErroradmitidoEditField        matlab.ui.control.NumericEditField
        GraficarButton                matlab.ui.control.Button
        CleanButton                   matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            funci = app.IngreseFuncinEditField.Value;
            f = str2func(['@(x)' funci]);
            flinea = 0;
            intervalo_menor = app.IntervalomenorEditField.Value;
            intervalo_mayor = app.IntervalomayorEditField.Value;
            
            hold(app.UIAxes,'on');
            cla(app.UIAxes);
            xline(app.UIAxes,0);
            fplot(app.UIAxes, f, [intervalo_menor, intervalo_mayor]);
            fplot(app.UIAxes,flinea,[intervalo_menor, intervalo_mayor]);
            ylim(app.UIAxes,[-10, 10]);
            hold(app.UIAxes,'off');
        end

        % Button pushed function: CleanButton
        function CleanButtonPushed(app, event)
            cla(app.UIAxes);
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            ea = app.ErroradmitidoEditField.Value;
            e = 100;
            x0 = app.XoEditField.Value; % Primer valor inicial
            x1 = app.XiEditField.Value; % Segundo valor inicial, puedes cambiarlo según sea necesario
            funci = app.IngreseFuncinEditField.Value;
            f = str2func(['@(x)' funci]);
            i = 0;
            
            % Inicializa 'mat' como una matriz vacía
            mat = [];
            
            while e >= ea || i == 0
                fnormal_x0 = f(x0);
                fnormal_x1 = f(x1);
                
                xi = x1 - (fnormal_x1 * (x1 - x0)) / (fnormal_x1 - fnormal_x0);
                
                if i > 0
                    e = abs((xi - x1) / xi) * 100;
                end
                
                i = i + 1;
                mat = [mat; i, x0,fnormal_x0, x1, fnormal_x1, xi, e];
                
                % Actualiza la tabla en la GUI
                hold(app.UIAxes,'on');
                xline(app.UIAxes,xi);
                hold(app.UIAxes,'off');
            
                app.UITable.Data= mat;
            
            	x0=x1; 
            	x1=xi; 
            end 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SecanteLabel = uilabel(app.UIFigure);
            app.SecanteLabel.Text = 'Secante';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'i'; 'Xi-i'; 'f(Xi-1)'; 'Xi'; 'f(Xi)'; 'Xi+1'; 'Ea'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Grafica')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.XoEditFieldLabel = uilabel(app.UIFigure);
            app.XoEditFieldLabel.Text = 'Xo';
            app.XoEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.XiEditFieldLabel = uilabel(app.UIFigure);
            app.XiEditFieldLabel.Text = 'Xi';
            app.XiEditField = uieditfield(app.UIFigure, 'numeric');
            app.IngreseFuncinEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseFuncinEditFieldLabel.Text = 'Ingrese Función';
            app.IngreseFuncinEditField = uieditfield(app.UIFigure, 'text');
            app.IntervalomenorEditFieldLabel = uilabel(app.UIFigure);
            app.IntervalomenorEditFieldLabel.Text = 'Intervalo menor';
            app.IntervalomenorEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntervalomayorEditFieldLabel = uilabel(app.UIFigure);
            app.IntervalomayorEditFieldLabel.Text = 'Intervalo mayor';
            app.IntervalomayorEditField = uieditfield(app.UIFigure, 'numeric');
            app.ErroradmitidoEditFieldLabel = uilabel(app.UIFigure);
            app.ErroradmitidoEditFieldLabel.Text = 'Error admitido';
            app.ErroradmitidoEditField = uieditfield(app.UIFigure, 'numeric');
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.CleanButton = uibutton(app.UIFigure, 'push');
            app.CleanButton.ButtonPushedFcn = createCallbackFcn(app, @CleanButtonPushed, true);
            app.CleanButton.Text = 'Clean';
        end
    end

    methods (Access = public)

        % Construct app
        function app = metodo4

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end