classdef sementos_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        fxEditFieldLabel       matlab.ui.control.Label
        fxEditField            matlab.ui.control.EditField
        aEditFieldLabel        matlab.ui.control.Label
        aEditField             matlab.ui.control.NumericEditField
        bEditField_2Label      matlab.ui.control.Label
        bEditField_2           matlab.ui.control.NumericEditField
        nEditFieldLabel        matlab.ui.control.Label
        nEditField             matlab.ui.control.NumericEditField
        SegmentosimparesLabel  matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
        UITable                matlab.ui.control.Table
        CalcularButton         matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            funcion = app.fxEditField.Value;
            f = str2func(['@(x)' funcion]);
            a = app.aEditField.Value;
            b = app.bEditField_2.Value;
            n = app.nEditField.Value;

                  h = ((b-a)/n);
                sum1 = (f(a)+f(h*(n-3)));
                sum2 = (f(h*(n-3))+f(b));
                    disp(sum1);
                    disp(sum2);
                    disp(h);
                for i = 1 : n - 4
                    x=i*h;
                    disp (i);
                   if mod(i,2) == 0
                       sum1 = sum1 + 2 * f(x);
                   else
                       sum1 = sum1 + 4 * f(x);
                   end
                    
                end
               I1 = (h / 3) * sum1;
                for i = n - 3 : n-1
                    x = i*h;
                     disp (i);
                    if mod(i,3)==0
                        sum2 = sum2 + f(x);
                    else
                        sum2 = sum2 + 3*f(x);
                    end
                end
                 I2 = (3 * h / 8) * sum2;
                
                I = I1+I2; 
                real = integral(f, a, b); 
                Er = ((real - I) / real) * 100; 
                datos = [n, h, I, Er]; 
                app.UITable.Data = datos; 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.fxEditFieldLabel = uilabel(app.UIFigure);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.fxEditField = uieditfield(app.UIFigure, 'text');
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField_2Label = uilabel(app.UIFigure);
            app.bEditField_2Label.Text = 'b';
            app.bEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.UIFigure, 'numeric');
            app.SegmentosimparesLabel = uilabel(app.UIFigure);
            app.SegmentosimparesLabel.Text = 'Segmentos impares';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'n'; 'h'; 'I'; 'Er'};
            app.UITable.RowName = {};
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
        end
    end

    methods (Access = public)

        % Construct app
        function app = sementos_2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end