classdef simpson3_8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        fxEditFieldLabel  matlab.ui.control.Label
        fxEditField       matlab.ui.control.EditField
        aEditFieldLabel   matlab.ui.control.Label
        aEditField        matlab.ui.control.NumericEditField
        bEditFieldLabel   matlab.ui.control.Label
        bEditField        matlab.ui.control.NumericEditField
        Simpson38Label    matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
        CalcularButton    matlab.ui.control.Button
        nEditFieldLabel   matlab.ui.control.Label
        nEditField        matlab.ui.control.NumericEditField
        UITable           matlab.ui.control.Table
    end

    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            
            funcion = app.fxEditField.Value;
            f = str2func(['@(x)' funcion]);
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            n = app.nEditField.Value;
            
            if n == 3
                 h = ((b-a)/n);
    
                 x = 0;
                sum = f(a) + f(b);
        
                    for i = 1:n-1
                        x = i*h;
                        if mod(i, 3) == 0
                            sum = sum + f(x);
                        else
                            sum = sum + 3*f(x);
                        end
                    end
               
                    I = ((b-a)*(sum/8));
                    real = integral(f, a, b);
                    Er = (((real - I)/real)*100);
                    datos = [n, h, I, Er];
                    app.UITable.Data = datos;
                    hold(app.UIAxes, 'on');
                    cla(app.UIAxes);
                    xline(app.UIAxes, 0, 'r'); % Línea vertical en x=0
                    xline(app.UIAxes, a, 'g'); % Línea vertical en x=a
                    xline(app.UIAxes, b, 'g'); % Línea vertical en x=b
                    fplot(app.UIAxes, f, [a b], 'b'); % Graficar la función f en el intervalo [a, b]
                    % Calcular puntos para el área sombreada
x_fill = linspace(a, b, 1000); % Generar puntos entre a y b
y_fill = arrayfun(f, x_fill); % Evaluar la función en esos puntos
fill(app.UIAxes, [a, x_fill, b], [0, y_fill, 0], 'b', 'FaceAlpha', 0.1, 'EdgeColor', 'none'); % Crear área sombreada
for i = 1:n
    x_vert = a + (i-1) * h;
    y_vert = f(x_vert);
    line(app.UIAxes, [x_vert, x_vert], [0, y_vert], 'Color', 'magenta', 'LineWidth', 1.5);
end
text(app.UIAxes, a, f(a), sprintf('a = %f', a), 'VerticalAlignment', 'bottom');
text(app.UIAxes, b, f(b), sprintf('b = %f', b), 'VerticalAlignment', 'bottom');
text(app.UIAxes, (a+b)/2, max(y_fill)/2, sprintf('Integral ≈ %f', I), 'HorizontalAlignment', 'center');


hold(app.UIAxes, 'off');
                                      
            else 
               errordlg('El valor de n debe ser 3', 'Error');
                
            end
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.fxEditFieldLabel = uilabel(app.UIFigure);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.fxEditField = uieditfield(app.UIFigure, 'text');
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.Simpson38Label = uilabel(app.UIFigure);
            app.Simpson38Label.Text = 'Simpson 3/8';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Grafica')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.UIFigure, 'numeric');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'i'; 'h'; 'Aproximacion'; 'Er'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = simpson3_8

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end